classdef ELI_Beamlines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FilesMenu                     matlab.ui.container.Menu
        UploadMenu                    matlab.ui.container.Menu
        ThresholdMenu                 matlab.ui.container.Menu
        CreateMaskMenu                matlab.ui.container.Menu
        ProcessImageMenu              matlab.ui.container.Menu
        FillHolesMenu                 matlab.ui.container.Menu
        WatershedMenu                 matlab.ui.container.Menu
        AnlayseParticlesMenu          matlab.ui.container.Menu
        GraphsMenu                    matlab.ui.container.Menu
        HistogramMenu                 matlab.ui.container.Menu
        EnergyDistributionMenu        matlab.ui.container.Menu
        SelectDataHistogramMenu       matlab.ui.container.Menu
        SelectDataEnergyDistributionMenu  matlab.ui.container.Menu
        SelectDataEnergyDistributionMediumMenu  matlab.ui.container.Menu
        DiameterLimitsPanel           matlab.ui.container.Panel
        DiameterLowerLimitEditField   matlab.ui.control.NumericEditField
        DiameterLowerLimitEditFieldLabel  matlab.ui.control.Label
        DiameterUpperLimitEditField   matlab.ui.control.NumericEditField
        DiameterUpperLimitEditFieldLabel  matlab.ui.control.Label
        AreaLimitsPanel               matlab.ui.container.Panel
        AreaLowerLimitEditField       matlab.ui.control.NumericEditField
        AreaLowerLimitEditFieldLabel  matlab.ui.control.Label
        AreaUpperLimitEditField       matlab.ui.control.NumericEditField
        AreaUpperLimitEditFieldLabel  matlab.ui.control.Label
        UITable3                      matlab.ui.control.Table
        UITable2                      matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        Panel                         matlab.ui.container.Panel
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        %initialising empty arrays 
        images = {};
        originalImages = {}; 
        watersheddedImages = {};
        current = 1; 
        analysedData;
        scaled_areas;
        unique_areas; 
        counts; 
        diameters; 
        unique_diameters; 
        energies; 
        energy_difference;
        newImagesUploaded = false;
    end

     methods (Access = private) 
        %slider limits
        function updateSliderLimits(app)
            tot = length(app.images);
            if tot > 1
                app.Slider.Limits = [1, tot];
                app.Slider.Value = 1;
                app.Slider.MajorTicks = 1:tot; % update ticks to reflect image count
            else
                app.Slider.Limits = [1, 1];
                app.Slider.Value = 1;
                app.Slider.MajorTicks = []; % no major ticks if only one image
            end
        end

        % Add a method to reset analysis data
        function resetAnalysisData(app)
            app.scaled_areas = [];
            app.unique_areas = [];
            app.counts = [];
            app.diameters = [];
            app.unique_diameters = [];
            app.energies = [];
            app.energy_difference = [];
            app.analysedData = []; 
            % Reset any other analysis-related properties here
        end

        % Modify other analysis methods to check the newImagesUploaded flag
        function SomeAnalysisMethod(app)
            if app.newImagesUploaded
                app.resetAnalysisData();
                app.newImagesUploaded = false;
            end
        end
     end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %defaults values for table data 
            app.UITable.Data = [0, 0, 0]; 
            app.UITable2.Data = [0, 0, 0];
            app.UITable3.Data = [0, 0, 0,0,0]; 
        end

        % Menu selected function: UploadMenu
        function UploadMenuSelected(app, event)
             % open file selection to choose images
             [filenames, filepath] = uigetfile('*.*', 'All Files (*.*)', 'Multiselect','on');

             % exit function early if upload canceled 
             if isequal(filenames, 0) || isequal(filepath, 0)
                 return;
             end

             % filesnames as cell array
             if ischar(filenames)
                filenames = {filenames};
            end

            %clear previously loaded images/ data 
            app.images={}; 
            app.originalImages = {}; 
            app.resetAnalysisData();

            % loop through files- read and process images
            for i = 1:length(filenames)
                fullfilename = fullfile(filepath, filenames{i});
                I = imread(fullfilename);
                RGBimage = I(:, :, 1:3); % reducing from 4 chanels 
             
                %storing into our empty image array 
                app.originalImages{end+1} = RGBimage;
                app.images{end+1} = RGBimage; 
            end

            % confirm new images uploaded 
            app.newImagesUploaded = true;

            %calling function updatesliderlimits 
            updateSliderLimits(app); 
            %if no images selected- show axes 
            if ~isempty(app.images)
                imshow(app.images{1},'Parent',app.UIAxes);
            end 
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.current = round(event.Value); %getting current value of slider
            if app.current <1 %value less than 1 = minimum 1 
                app.current =1; 
            elseif app.current >length(app.images) %setting max number (length)
                app.current = length(app.images); 
            end
            %displaying the images in the UIAxe
            if ~isempty(app.images)
                imshow(app.images{app.current}, 'Parent',app.UIAxes);
            end
        end

        % Menu selected function: CreateMaskMenu
        function CreateMaskMenuSelected(app, event)
            values = app.UITable.Data;
            Threshold = values(2); 
             if Threshold == 0 
                uialert(app.UIFigure, 'Please enter valid threshold limits.', 'Input Required');
                return; % exit function early
             end

             if Threshold < 0 || Threshold > 255
                uialert(app.UIFigure, 'Threshold values must be between 0 and 255.', 'Invalid Input');
                return; % exit function early
             end

            % loop through all loaded images and replace with binary images
            for i = 1:length(app.originalImages)
                image = app.originalImages{i};

                % convert to grayscale and create mask
                I_8bit = im2gray(image);
                
                % use threshold from edit field
                mask = I_8bit < Threshold; 

                % replace image with binary image
                app.images{i} = mask; 

            end

            % updating images
            updateSliderLimits(app);
            if ~isempty(app.images)
                imshow(app.images{i}, 'Parent', app.UIAxes);
            end
        end

        % Menu selected function: FillHolesMenu
        function FillHolesMenuSelected(app, event)
            % check if there are masks
            if isempty(app.images)
                uialert(app.UIFigure, 'No masks available to fill holes. Please create masks first.', 'No Masks');
                return; % exit function early
            end 

            % initialize filledMasks as cell array
            filledMasks = cell(1, length(app.images));

            % loop through masks and fill holes
            for i = 1:length(app.images)
                bw = app.images{i}; % get binary mask
                filledMasks{i} = imfill(bw, 'holes'); % fill holes in mask and store in cell array
            end

            % update app.images with filled masks
            app.images = filledMasks;
        
            % updating images
            updateSliderLimits(app); 
            if ~isempty(app.images)
                imshow(app.images{i}, 'Parent', app.UIAxes); 
            end
        end

        % Menu selected function: AnlayseParticlesMenu
        function AnlayseParticlesMenuSelected(app, event)
            values = app.UITable.Data;
            if isempty(app.watersheddedImages)
                uialert(app.UIFigure, 'No images available for analysis.', 'No Images');
                return; % exit function early
            end

            app.scaled_areas = [];
            app.analysedData = [];

            %calling on inputs
            circularity_threshold = values(3); 
            scale = values(1); 
            Area_Upper = app.AreaUpperLimitEditField.Value;
            Area_Lower = app.AreaLowerLimitEditField.Value;
            
            %initialising data cell arrays
            app.analysedData = cell(1, length(app.images));

            % loop through all filled masks
            for i = 1:length(app.watersheddedImages)
            % get current mask
                RGBimage = app.watersheddedImages{i};
        
                % find boundaries
                [B, L] = bwboundaries(RGBimage, 'noholes',TraceStyle='pixeledge');  

                % get region properties
                stats = regionprops(L, "Circularity", "Centroid", "Area");
          
                figure;
                imshow(L);
                hold on;
        
                % loop through each particle
                for j = 1:length(B)
                    boundary = B{j};
                    circularity = stats(j).Circularity;
                    if circularity > circularity_threshold
                        area = stats(j).Area;
                        scaled_area = area / scale^2;
                        if scaled_area > Area_Lower && scaled_area < Area_Upper 
                            plot(boundary(:,2), boundary(:,1), 'cyan', 'LineWidth', 2);
                            app.scaled_areas(end+1) = scaled_area; 
                            centroid = stats(j).Centroid; 
                            text(centroid(1), centroid(2), num2str(j), 'Color', 'r', 'FontSize', 9, 'HorizontalAlignment', 'center');
                        end
                    end
                end
                drawnow;

                % save figure as image
                F = getframe(gca); 
                img = frame2im(F); 
    
                % store image in cell array
                analysedImages{i} = img;
 
            end
            % updating image array 
            app.images = analysedImages;  

            % updating images
            updateSliderLimits(app); 
            if ~isempty(app.images)
                imshow(app.images{app.current}, 'Parent', app.UIAxes);
            end
        end

        % Menu selected function: WatershedMenu
        function WatershedMenuSelected(app, event)
              % check if there are images 
            if isempty(app.images)
                uialert(app.UIFigure, 'No masks available to fill holes. Please create masks first.', 'No Masks');
                return; % exit function early
            end 
            
            %gaussian bluring  
            watershedI = cell(1, length(app.images));

             for i = 1:length(app.images)
                bw_filled_blur = imgaussfilt(double(app.images{i}),1);
          
                  %watershedding 
                    D = -bwdist(~bw_filled_blur);
                    W = watershed(D);
                    
                    W(~app.images{i}) = 0;
                    watershedMask = W == 0;
                    watershedI{i} = watershedMask; 
             end

            %inverting mask
            for i = 1:length(watershedI)
                watershedI{i} = ~watershedI{i};  
            end
            
            %updating app.images to include watershedded image
            app.watersheddedImages = watershedI;
            app.images = app.watersheddedImages; 
            
            updateSliderLimits(app);
            % updating images
            if ~isempty(app.images)
                imshow(app.images{app.current}, 'Parent', app.UIAxes);
            end
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            % unique areas and frequency 
            [app.unique_areas, ~, indices] = unique(app.scaled_areas);
            app.counts = histcounts(indices, length(app.unique_areas));
            
            % plot histogram
            figure;
            bar(app.unique_areas, app.counts); 
            xlabel('Area');
            ylabel('Count');
            title('Histogram of Particle Areas'); 
        end

        % Menu selected function: EnergyDistributionMenu
        function EnergyDistributionMenuSelected(app, event)
             %calling inputs
             values = app.UITable2.Data;
             params = app.UITable3.Data; 
             num_shots = values(1); 
             distance = values(2); 
             CR_area = values(3); 
             diameter_upper = app.DiameterUpperLimitEditField.Value;
             diameter_lower = app.DiameterLowerLimitEditField.Value;
            
             app.diameters= []; 
             app.unique_diameters = []; 
             app.energies = []; 
             app.energy_difference = []; 

             % calculating diameters
             for i= 1:length(app.scaled_areas) 
                diameter = sqrt((app.scaled_areas(i)*4) / pi);
                app.diameters(end+1) = diameter; 
             end 

             %calculating unique diameters
             [app.unique_diameters, ~, ~] = unique(app.diameters);
            
             %calculations
             %energy calc
             energies_calc = params(2) * exp(-(app.unique_diameters) / params(3)) + ...
                 params(4) * exp(-(app.unique_diameters) / params(5)) + params(1);
             app.energies = [app.energies, energies_calc];
            
             %change in energy
             energy_diff_calc = abs(diff(app.energies));
             if energy_diff_calc <length(app.scaled_areas) %the energy_difference is empty...
                energy_diff_calc(end + 1) = energy_diff_calc(end);
             end
             app.energy_difference = [app.energy_difference, energy_diff_calc];
             
             %solid angle
             solid_angle = CR_area/((distance)^2);
             sr_s= solid_angle*num_shots;

             % apply diameter limits and finding filtered arrays 
             diameter_limits = app.unique_diameters > diameter_lower & app.unique_diameters < diameter_upper;
             filtered_diameters = app.unique_diameters(diameter_limits);
             filtered_counts = app.counts(diameter_limits);
             filtered_energies = app.energies(diameter_limits);
             filtered_energy_diff = app.energy_difference(diameter_limits);

             %dN/dE:
             dN_dE = filtered_counts ./ filtered_energy_diff;
            
             %plotting energy distribution
             y_axis = [];
             for i =1:length(dN_dE)
                 y= dN_dE(i)/sr_s;
                 y_axis = [y_axis, y];
             end
             x_axis= filtered_energies;

             % plot with log y-axis
             figure;
             semilogy(x_axis, y_axis, 'o');

             % labels and title
             xlabel('Energy (MeV)');
             ylabel('(dN/dE)/(sr*shot)');
             title('Energy Distribution (5um)');

             %saving data 
             %defining the table data 
             data_array = {app.unique_areas, app.unique_diameters, app.counts, app.energies, app.energy_difference,...
                 filtered_diameters, filtered_counts, filtered_energies, filtered_energy_diff, ...
                 dN_dE, y_axis};
             
             %ensuring each column has same number of rows 
             maxLength = max(cellfun(@length, data_array));
             paddedArrays = cellfun(@(x) [x(:); NaN(maxLength-length(x), 1)], data_array, 'UniformOutput', false);
             dataMatrix = [paddedArrays{:}];
             % defining the headers of the table 
             T = array2table(dataMatrix, 'VariableNames' , {'Areas', 'Diameters', 'Counts', 'Energy', 'Energy Difference',...
                 'Limited Diameter', 'Limited Counts',...
                 'Limited Energy','Limited Energy Difference','dN_dE', 'dN_dE_sr_s'});

             % where to save the file
             [file, path] = uiputfile('*.csv', 'Save CSV file');
             if ischar(file)
                 filename = fullfile(path, file);
                 % Write table to CSV file with headers
                 writetable(T, filename);
             end
        end

        % Menu selected function: SelectDataHistogramMenu
        function SelectDataHistogramMenuSelected(app, event)
            % select csv files
            [files, path] = uigetfile('*.csv', 'Select one or more CSV files', 'MultiSelect', 'on');

            if ischar(files)
                files = {files};  % if only one file is selected, convert to cell array
            end

            % initialize arrays to store data
            all_x_data = {};
            all_y_data = {};
            legends = {}; % for storing legends

            % loop over selected files
            for i = 1:length(files)
                filename = fullfile(path, files{i});
                data = readtable(filename);

                % energy data is in the 'Limited Energy' column
                all_x_data{i} = data.Areas;

                % y-axis data is in the 'dN_dE_per_sr_s' column
                all_y_data{i} = data.Counts;

                % add file name to legend
                [~, name, ~] = fileparts(files{i});
                legends{end+1} = name;
            end

            % plotting graph
            figure;
            hold on;

            colors = lines(length(files));
            for i = 1:length(all_x_data)
                bar(all_x_data{i}, all_y_data{i}, 'FaceColor', colors(i,:), 'DisplayName', legends{i}, 'FaceAlpha', 0.6);
            end

            % formatting graph
            xlabel('Area');
            ylabel('Count');
            title('Histogram of Particle Areas'); 
            legend('show');
            hold off;
        end

        % Menu selected function: SelectDataEnergyDistributionMenu
        function SelectDataEnergyDistributionMenuSelected(app, event)
            % select csv files
            [files, path] = uigetfile('*.csv', 'Select one or more CSV files', 'MultiSelect', 'on');

            if ischar(files)
                files = {files};  % if only one file is selected, convert to cell array
            end

            % initialize arrays to store data
            all_x_data = {};
            all_y_data = {};
            legends = {}; % for storing legends

            % loop over selected files
            for i = 1:length(files)
                filename = fullfile(path, files{i});
                data = readtable(filename);

                % energy data is in the 'Limited Energy' column
                all_x_data{i} = data.LimitedEnergy;

                % y-axis data is in the 'dN_dE_sr_s' column
                all_y_data{i} = data.dN_dE_sr_s;

                % add file name to legend
                [~, name, ~] = fileparts(files{i});
                legends{end+1} = name;
            end

            % plotting graph
            figure;
            hold on;
            for i = 1:length(all_x_data)
                semilogy(all_x_data{i}, all_y_data{i}, 'DisplayName', legends{i});
            end
         
            % add labels and title
            xlabel('Energy (MeV)');
            ylabel('(dN/dE)/(sr*shot)');
             title('Energy Distributions');
             legend('show');
             hold off;
        end

        % Menu selected function: SelectDataEnergyDistributionMediumMenu
        function SelectDataEnergyDistributionMediumMenuSelected(app, event)
              % select csv files
            [files, path] = uigetfile('*.csv', 'Select one or more CSV files', 'MultiSelect', 'on');

            if ischar(files)
                files = {files};  % if only one file is selected, convert to cell array
            end

            % initialize arrays to store combined data
            all_x_data = {};
            all_y_data = {};

            % loop over selected files
            for i = 1:length(files)
                filename = fullfile(path, files{i});
                data = readtable(filename);

                % energy data is in the 'Limited Energy' column
                all_x_data{i} = data.LimitedEnergy;

                % y-axis data is in the 'dN_dE_sr_s' column
                all_y_data{i} = data.dN_dE_sr_s;
            end

            % convert to matrices for easier manipulation and adding NaN to
            % shorter arrays 
            maxLength = max(cellfun(@length, all_x_data));
            x_matrix = NaN(maxLength, length(all_x_data));
            y_matrix = NaN(maxLength, length(all_y_data));

            for i = 1:length(all_x_data)
                x_matrix(1:length(all_x_data{i}), i) = all_x_data{i};
                y_matrix(1:length(all_y_data{i}), i) = all_y_data{i};
            end

            % calculating max, min, and mean 
            y_max = max(y_matrix, [], 2, 'omitnan');
            y_min = min(y_matrix, [], 2, 'omitnan');
            y_mean = mean([y_max, y_min], 2, 'omitnan');

            figure;
            hold on;

            % plot the max, min, and mean curves
            plot(x_matrix(:, 1), y_max, 'b', 'LineWidth', 1, 'DisplayName', 'Max Curve');
            plot(x_matrix(:, 1), y_min, 'b', 'LineWidth', 1, 'DisplayName', 'Min Curve');
            plot(x_matrix(:, 1), y_mean, 'r', 'LineWidth', 1, 'DisplayName', 'Mean Curve');

            % add labels and title
            xlabel('Energy (MeV)');
            ylabel('(dN/dE)/(sr*shot)');
            title('Energy Distributions with Max, Min, and Mean Curves');
            legend('show');
            hold off;

            %saving data
            save_data = [y_max(:), y_min(:), y_mean(:)];

            T = array2table(save_data, 'VariableNames' , {'Maximum', 'Minimum', 'Mean'});

            % where to save the file
            [file, path] = uiputfile('*.csv', 'Save CSV file');
            if ischar(file)
                filename = fullfile(path, file);
                % write table to CSV file with headers
                writetable(T, filename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilesMenu = uimenu(app.UIFigure);
            app.FilesMenu.Text = 'Files      ';
            app.UploadMenu = uimenu(app.FilesMenu);
            app.UploadMenu.MenuSelectedFcn = createCallbackFcn(app, @UploadMenuSelected, true);
            app.UploadMenu.Text = 'Upload';
            app.ThresholdMenu = uimenu(app.UIFigure);
            app.ThresholdMenu.Text = 'Threshold      ';
            app.CreateMaskMenu = uimenu(app.ThresholdMenu);
            app.CreateMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateMaskMenuSelected, true);
            app.CreateMaskMenu.Text = 'Create Mask';
            app.ProcessImageMenu = uimenu(app.UIFigure);
            app.ProcessImageMenu.Text = 'Process Image      ';
            app.FillHolesMenu = uimenu(app.ProcessImageMenu);
            app.FillHolesMenu.MenuSelectedFcn = createCallbackFcn(app, @FillHolesMenuSelected, true);
            app.FillHolesMenu.Text = 'Fill Holes';
            app.WatershedMenu = uimenu(app.ProcessImageMenu);
            app.WatershedMenu.MenuSelectedFcn = createCallbackFcn(app, @WatershedMenuSelected, true);
            app.WatershedMenu.Text = 'Watershed';
            app.AnlayseParticlesMenu = uimenu(app.UIFigure);
            app.AnlayseParticlesMenu.MenuSelectedFcn = createCallbackFcn(app, @AnlayseParticlesMenuSelected, true);
            app.AnlayseParticlesMenu.Text = 'Anlayse Particles';
            app.GraphsMenu = uimenu(app.UIFigure);
            app.GraphsMenu.Text = 'Graphs';
            app.HistogramMenu = uimenu(app.GraphsMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.EnergyDistributionMenu = uimenu(app.GraphsMenu);
            app.EnergyDistributionMenu.MenuSelectedFcn = createCallbackFcn(app, @EnergyDistributionMenuSelected, true);
            app.EnergyDistributionMenu.Text = 'Energy Distribution';
            app.SelectDataHistogramMenu = uimenu(app.GraphsMenu);
            app.SelectDataHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDataHistogramMenuSelected, true);
            app.SelectDataHistogramMenu.Text = 'Select Data- Histogram';
            app.SelectDataEnergyDistributionMenu = uimenu(app.GraphsMenu);
            app.SelectDataEnergyDistributionMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDataEnergyDistributionMenuSelected, true);
            app.SelectDataEnergyDistributionMenu.Text = 'Select Data- Energy Distribution ';
            app.SelectDataEnergyDistributionMediumMenu = uimenu(app.GraphsMenu);
            app.SelectDataEnergyDistributionMediumMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDataEnergyDistributionMediumMenuSelected, true);
            app.SelectDataEnergyDistributionMediumMenu.Text = 'Select Data- Energy Distribution Medium ';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Microscope Images')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Scale'; 'Threshold'; 'Circularity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'# of Shots'; 'Distance from Source'; 'CR-39 Area'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Y0'; 'A1'; 'T1'; 'A2'; 'T2'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.AreaLimitsPanel = uipanel(app.UIFigure);
            app.AreaLimitsPanel.Title = 'Area Limits';
            app.AreaUpperLimitEditFieldLabel = uilabel(app.AreaLimitsPanel);
            app.AreaUpperLimitEditFieldLabel.Text = {'Area'; 'Upper Limit'};
            app.AreaUpperLimitEditField = uieditfield(app.AreaLimitsPanel, 'numeric');
            app.AreaLowerLimitEditFieldLabel = uilabel(app.AreaLimitsPanel);
            app.AreaLowerLimitEditFieldLabel.Text = {'Area'; 'Lower Limit'};
            app.AreaLowerLimitEditField = uieditfield(app.AreaLimitsPanel, 'numeric');
            app.DiameterLimitsPanel = uipanel(app.UIFigure);
            app.DiameterLimitsPanel.Title = 'Diameter Limits';
            app.DiameterUpperLimitEditFieldLabel = uilabel(app.DiameterLimitsPanel);
            app.DiameterUpperLimitEditFieldLabel.Text = {'Diameter'; 'Upper Limit'};
            app.DiameterUpperLimitEditField = uieditfield(app.DiameterLimitsPanel, 'numeric');
            app.DiameterLowerLimitEditFieldLabel = uilabel(app.DiameterLimitsPanel);
            app.DiameterLowerLimitEditFieldLabel.Text = {'Diameter'; 'Lower Limit'};
            app.DiameterLowerLimitEditField = uieditfield(app.DiameterLimitsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELI_Beamlines

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end